classdef my_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AsynchronousMotorUIFigure  matlab.ui.Figure
        TabGroup2                  matlab.ui.container.TabGroup
        ParametersTab              matlab.ui.container.Tab
        Image                      matlab.ui.control.Image
        MotorPickerButtonGroup     matlab.ui.container.ButtonGroup
        Motor5Button               matlab.ui.control.RadioButton
        Motor4Button               matlab.ui.control.RadioButton
        Motor3Button               matlab.ui.control.RadioButton
        Motor2Button               matlab.ui.control.RadioButton
        Motor1Button               matlab.ui.control.RadioButton
        BmEditField                matlab.ui.control.NumericEditField
        BmEditFieldLabel           matlab.ui.control.Label
        PEditField                 matlab.ui.control.NumericEditField
        PEditFieldLabel            matlab.ui.control.Label
        JEditField                 matlab.ui.control.NumericEditField
        JEditFieldLabel            matlab.ui.control.Label
        LlrEditField               matlab.ui.control.NumericEditField
        LlrEditFieldLabel          matlab.ui.control.Label
        SetParametersButton        matlab.ui.control.Button
        LmEditField                matlab.ui.control.NumericEditField
        LmEditFieldLabel           matlab.ui.control.Label
        LlsEditField               matlab.ui.control.NumericEditField
        LlsEditFieldLabel          matlab.ui.control.Label
        RrEditField                matlab.ui.control.NumericEditField
        RrEditFieldLabel           matlab.ui.control.Label
        RsEditField                matlab.ui.control.NumericEditField
        RsEditFieldLabel           matlab.ui.control.Label
        SimulateTab                matlab.ui.container.Tab
        HTML                       matlab.ui.control.HTML
        VirtualRealityLabel        matlab.ui.control.Label
        Switch                     matlab.ui.control.ToggleSwitch
        SimStepSizeEditField       matlab.ui.control.NumericEditField
        SimStepSizeEditFieldLabel  matlab.ui.control.Label
        SimStopTimeEditField       matlab.ui.control.NumericEditField
        SimStopTimeEditFieldLabel  matlab.ui.control.Label
        AmplitudeEditField         matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel    matlab.ui.control.Label
        FrequencyEditField         matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel    matlab.ui.control.Label
        AmplitudeSliderLabel       matlab.ui.control.Label
        AmplitudeSlider            matlab.ui.control.Slider
        FrequencySliderLabel       matlab.ui.control.Label
        FrequencySlider            matlab.ui.control.Slider
        SimulateButton             matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        GraphTab                   matlab.ui.container.Tab
        IqrCheckBox                matlab.ui.control.CheckBox
        IdrCheckBox                matlab.ui.control.CheckBox
        IqsCheckBox                matlab.ui.control.CheckBox
        IdsCheckBox                matlab.ui.control.CheckBox
        SignalAxes                 matlab.ui.control.UIAxes
        CurrentAxes                matlab.ui.control.UIAxes
        TorqueAxes                 matlab.ui.control.UIAxes
        WrAxes                     matlab.ui.control.UIAxes
        CompareTab                 matlab.ui.container.Tab
        ResetButton                matlab.ui.control.Button
        Sample4Label               matlab.ui.control.Label
        Sample3Label               matlab.ui.control.Label
        Sample2Label               matlab.ui.control.Label
        Sample1Label               matlab.ui.control.Label
        SimDataTable               matlab.ui.control.Table
        VfGraphAxes                matlab.ui.control.UIAxes
        TWGraphAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        wPlot;
        torquePlot;
        currentPlot;
        signalPlot;
        TwPlot;
        torqueData{};
        wrData{};
        VamplData{};
        FreqData{};
        animationEnabled;
        
    end
    methods (Access = private)
        
           function plotData(app)
            % Clear the TWGraphAxes
            cla(app.TWGraphAxes, 'reset');
            
            legends = cell(1, length(app.torqueData));
            % Plot each torque and wr data set
            for i = 1:length(app.torqueData)
                plot(app.TWGraphAxes, app.wrData{i},app.torqueData{i});
                hold(app.TWGraphAxes, 'on');
                legends{i} = ['Sample ' num2str(i)];
            end
            legend(app.TWGraphAxes, legends, 'Location', 'best');
            
            title(app.TWGraphAxes, 'Torque / Angular Speed ')
            xlabel(app.TWGraphAxes, 'W(rpm)')
            ylabel(app.TWGraphAxes, 'Torque')
            zlabel(app.TWGraphAxes, 'Z')
            app.TWGraphAxes.FontWeight = 'bold';
            app.TWGraphAxes.XGrid = 'on';
            app.TWGraphAxes.YGrid = 'on';
            
            hold(app.TWGraphAxes, 'off');
          end


            function plotVfData(app)
            % Clear the VfGraphAxes
            cla(app.VfGraphAxes, 'reset');


            legends = cell(1, length(app.VamplData));
            colors = lines(length(app.VamplData));  % Renkleri al
            markerSize = 10;  % Nokta boyutunu ayarla
        
            % Plot each Vampl and Freq data set
            hold(app.VfGraphAxes, 'on');
            for i = 1:length(app.VamplData)
                sampleCount = i;  
                ratio = app.VamplData{i} ./ app.FreqData{i};  % V/f oranını hesapla
                plot(app.VfGraphAxes, sampleCount, ratio, 'o', 'MarkerSize', markerSize, 'Color', colors(i, :), 'MarkerFaceColor', colors(i, :));
                legends{i} = ['Sample ' num2str(sampleCount)];
            end
        
            % Set the labels, title, legend, and other properties
            title(app.VfGraphAxes, 'Amplitude / Frequency (V/f)');
            xlabel(app.VfGraphAxes, 'Sample Number');
            ylabel(app.VfGraphAxes, 'V/f ratio');
            app.VfGraphAxes.FontWeight = 'bold';
            app.VfGraphAxes.XGrid = 'on';
            app.VfGraphAxes.YGrid = 'on';
            app.VfGraphAxes.XLim = [0.5 sampleCount + 0.5];  % Adjust the x-axis limits
            legend(app.VfGraphAxes, legends, 'Location', 'bestoutside');
            hold(app.VfGraphAxes, 'off');
        end


           function resetData(app)
            % Reset the VamplData and FreqData arrays
            app.torqueData = {};
            app.wrData = {};
            app.VamplData = {};
            app.FreqData = {};
            
            % Clear table data
            app.SimDataTable.Data = [];

            % Clear the VfGraphAxes
            cla(app.TWGraphAxes, 'reset');
            cla(app.VfGraphAxes, 'reset');
          end


     end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.HTML.Visible="off";
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            % get params
            Rs = app.RsEditField.Value;assignin('base', 'Rs', Rs);
            R1r = app.RrEditField.Value;assignin('base', 'R1r', R1r);
            Lls = app.LlsEditField.Value;assignin('base', 'Lls', Lls);
            Lm = app.LmEditField.Value;assignin('base', 'Lm', Lm);
            L1lr = app.LlrEditField.Value;assignin('base', 'L1lr', L1lr);
            J = app.JEditField.Value;assignin('base', 'J', J);
            P = app.PEditField.Value;assignin('base', 'P', P);
            Bm = app.BmEditField.Value;assignin('base', 'Bm', Bm);

            % inits=[Rs,R1r,Lls,Lm,L1lr,J,P,Bm];
            % % assign to workspace
            % assignin('base', 'inits', inits);


        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            app.FrequencySlider.Value=value;
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
            changingValue = event.Value;
            app.FrequencyEditField.Value=changingValue;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.HTML.Visible="on";
            Freq=app.FrequencyEditField.Value;
            Vampl=app.AmplitudeEditField.Value;
            w=2*pi*Freq;
            assignin('base',"w",w);
            assignin('base',"Freq",Freq);
            assignin('base',"Vampl",Vampl);
            SetParametersButtonPushed(app);
            

            if strcmpi(app.animationEnabled, 'on')
                variantChoice = 1; % Multibody varyantını seç
                disp('Mechanics Explorer will be opened.');
            else
                variantChoice = 0;
            end
            assignin('base', 'variantChoice', variantChoice);
          
           
            mdl = "async_sim";
            
            load_system(mdl);
            

            tstop = app.SimStopTimeEditField.Value;
            my_stepsize= app.SimStepSizeEditField.Value;

            set_param(mdl, "StopTime", num2str(tstop));
            set_param(mdl,'MaxStep',num2str(my_stepsize));
            

            simout = sim(mdl);

            assignin('base', 'simout', simout);
            

            t = simout.tout;
            wr = simout.yout(:, 1);
            torque=simout.yout(:,2);
            ids = simout.yout(:,3);
            iqs = simout.yout(:,4);
            idr = simout.yout(:,5);
            iqr = simout.yout(:,6);
            va = simout.yout(:,7);
            vb = simout.yout(:,8);
            vc = simout.yout(:,9);
            %th = simout.yout(:,10);

            app.wPlot=plot(app.WrAxes,t,wr);
            app.torquePlot = plot(app.TorqueAxes,t,torque);
            app.currentPlot = plot(app.CurrentAxes,t,ids,t,iqs,t,idr,t,iqr);
            app.signalPlot = plot(app.SignalAxes,t,va,t,vb,t,vc);
            legend(app.CurrentAxes,'Ids','Iqs','Idr','Iqr');

            newdata = table(Vampl, Freq, torque(end), wr(end), 'VariableNames', {'Vampl', 'Freq', 'Torque', 'wr'});
            if height(app.SimDataTable.Data) < 4
                simdata = [app.SimDataTable.Data; newdata];
            else
                simdata = [app.SimDataTable.Data(2:end, :); newdata];
            end
            app.SimDataTable.Data = simdata;

            app.torqueData{end+1} = torque;
            app.wrData{end+1} = wr;
            app.VamplData{end+1} = Vampl;
            app.FreqData{end+1} = Freq;

            if length(app.torqueData) > 4
                app.torqueData = app.torqueData(2:end);
                app.wrData = app.wrData(2:end);
                app.VamplData = app.VamplData(2:end);
                app.FreqData = app.FreqData(2:end);
            end
            
            app.plotData();
            app.plotVfData();
            app.HTML.Visible="off";

        end

        % Value changing function: AmplitudeSlider
        function AmplitudeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.AmplitudeEditField.Value=changingValue;
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            value = app.AmplitudeEditField.Value;
            app.AmplitudeSlider.Value=value;
        end

        % Value changed function: IdrCheckBox
        function IdrCheckBoxValueChanged(app, event)
            value = app.IdrCheckBox.Value;
            if value == 0
                app.currentPlot(3).Visible = 'off';
            elseif value == 1
                app.currentPlot(3).Visible = 'on';

            end
        end

        % Value changed function: IqsCheckBox
        function IqsCheckBoxValueChanged(app, event)
            value = app.IqsCheckBox.Value;
             if value == 0
                app.currentPlot(2).Visible = 'off';
            elseif value == 1
                app.currentPlot(2).Visible = 'on';

            end
        end

        % Value changed function: IdsCheckBox
        function IdsCheckBoxValueChanged(app, event)
            value = app.IdsCheckBox.Value;
            if value == 0
                app.currentPlot(1).Visible = 'off';
            elseif value == 1
                app.currentPlot(1).Visible = 'on';

            end
        end

        % Value changed function: IqrCheckBox
        function IqrCheckBoxValueChanged(app, event)
            value = app.IqrCheckBox.Value;
            if value == 0
                app.currentPlot(4).Visible = 'off';
            elseif value == 1
                app.currentPlot(4).Visible = 'on';

            end
        end

        % Callback function: Image, MotorPickerButtonGroup
        function MotorPickerButtonGroupSelectionChanged(app, event)
            selectedButton = app.MotorPickerButtonGroup.SelectedObject;  
            if selectedButton == app.Motor1Button
                app.RsEditField.Value=2;
                app.RrEditField.Value=1.7;
                app.LlsEditField.Value=0.008;
                app.LmEditField.Value=0.46;
                app.LlrEditField.Value=0.030;
                app.JEditField.Value=0.012;
                app.PEditField.Value=2;
                app.BmEditField.Value=0.02;
                app.Image.ImageSource='img_motor1.jpg';
            elseif selectedButton == app.Motor2Button
                app.RsEditField.Value=2.4;
                app.RrEditField.Value=2.7;
                app.LlsEditField.Value=0.018;
                app.LmEditField.Value=0.56;
                app.LlrEditField.Value=0.060;
                app.JEditField.Value=0.042;
                app.PEditField.Value=2;
                app.BmEditField.Value=0.03;
                app.Image.ImageSource='img_motor2.jpg';
            elseif selectedButton == app.Motor3Button
                app.RsEditField.Value=3;
                app.RrEditField.Value=1.1;
                app.LlsEditField.Value=0.020;
                app.LmEditField.Value=0.76;
                app.LlrEditField.Value=0.020;
                app.JEditField.Value=0.031;
                app.PEditField.Value=4;
                app.BmEditField.Value=0.04;
                app.Image.ImageSource='img_motor3.jpg';
            elseif selectedButton == app.Motor4Button
                app.RsEditField.Value=4;
                app.RrEditField.Value=3.7;
                app.LlsEditField.Value=0.08;
                app.LmEditField.Value=0.26;
                app.LlrEditField.Value=0.050;
                app.JEditField.Value=0.042;
                app.PEditField.Value=3;
                app.BmEditField.Value=0.01;
                app.Image.ImageSource='img_motor4.jpg';
            elseif selectedButton == app.Motor5Button
                app.RsEditField.Value=0;
                app.RrEditField.Value=0;
                app.LlsEditField.Value=0;
                app.LmEditField.Value=0;
                app.LlrEditField.Value=0;
                app.JEditField.Value=0;
                app.PEditField.Value=0;
                app.BmEditField.Value=0;
                app.Image.ImageSource='img_motor5.jpg';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           app.resetData(); 
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.animationEnabled = value;
            fprintf('Switch Value: %s\n', value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AsynchronousMotorUIFigure = uifigure('Visible', 'off');
            app.AsynchronousMotorUIFigure.Name = 'Asynchronous Motor';
            app.TabGroup = uitabgroup(app.AsynchronousMotorUIFigure);
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.WrAxes = uiaxes(app.GraphTab);
            title(app.WrAxes, 'Motor Speed')
            xlabel(app.WrAxes, '(second)')
            ylabel(app.WrAxes, '(rad/s)')
            zlabel(app.WrAxes, 'Z')
            app.TorqueAxes = uiaxes(app.GraphTab);
            title(app.TorqueAxes, 'Torque')
            xlabel(app.TorqueAxes, '(second)')
            ylabel(app.TorqueAxes, '(Nm)')
            zlabel(app.TorqueAxes, 'Z')
            app.CurrentAxes = uiaxes(app.GraphTab);
            title(app.CurrentAxes, 'Current')
            xlabel(app.CurrentAxes, '(second)')
            ylabel(app.CurrentAxes, '(Ampere)')
            zlabel(app.CurrentAxes, 'Z')
            app.SignalAxes = uiaxes(app.GraphTab);
            title(app.SignalAxes, 'Input Signal Va Vb Vc')
            xlabel(app.SignalAxes, '(second)')
            ylabel(app.SignalAxes, '(Volt)')
            zlabel(app.SignalAxes, 'Z')
            app.IdsCheckBox = uicheckbox(app.GraphTab);
            app.IdsCheckBox.ValueChangedFcn = createCallbackFcn(app, @IdsCheckBoxValueChanged, true);
            app.IdsCheckBox.Text = 'Ids';
            app.IdsCheckBox.Value = true;
            app.IqsCheckBox = uicheckbox(app.GraphTab);
            app.IqsCheckBox.ValueChangedFcn = createCallbackFcn(app, @IqsCheckBoxValueChanged, true);
            app.IqsCheckBox.Text = 'Iqs';
            app.IqsCheckBox.Value = true;
            app.IdrCheckBox = uicheckbox(app.GraphTab);
            app.IdrCheckBox.ValueChangedFcn = createCallbackFcn(app, @IdrCheckBoxValueChanged, true);
            app.IdrCheckBox.Text = 'Idr';
            app.IdrCheckBox.Value = true;
            app.IqrCheckBox = uicheckbox(app.GraphTab);
            app.IqrCheckBox.ValueChangedFcn = createCallbackFcn(app, @IqrCheckBoxValueChanged, true);
            app.IqrCheckBox.Text = 'Iqr';
            app.IqrCheckBox.Value = true;
            app.CompareTab = uitab(app.TabGroup);
            app.CompareTab.Title = 'Compare';
            app.TWGraphAxes = uiaxes(app.CompareTab);
            title(app.TWGraphAxes, 'Torque / Angular Speed ')
            xlabel(app.TWGraphAxes, 'W(rpm)')
            ylabel(app.TWGraphAxes, 'Torque')
            zlabel(app.TWGraphAxes, 'Z')
            app.VfGraphAxes = uiaxes(app.CompareTab);
            title(app.VfGraphAxes, 'V/f')
            xlabel(app.VfGraphAxes, 'Amplitude')
            ylabel(app.VfGraphAxes, 'Frequency')
            zlabel(app.VfGraphAxes, 'Z')
            app.SimDataTable = uitable(app.CompareTab);
            app.SimDataTable.ColumnName = {'Amplitude'; 'Frequency'; 'Torque'; 'Angular Speed'};
            app.SimDataTable.RowName = {};
            app.Sample1Label = uilabel(app.CompareTab);
            app.Sample1Label.Text = 'Sample 1';
            app.Sample2Label = uilabel(app.CompareTab);
            app.Sample2Label.Text = 'Sample 2';
            app.Sample3Label = uilabel(app.CompareTab);
            app.Sample3Label.Text = 'Sample 3';
            app.Sample4Label = uilabel(app.CompareTab);
            app.Sample4Label.Text = 'Sample 4';
            app.ResetButton = uibutton(app.CompareTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TabGroup2 = uitabgroup(app.AsynchronousMotorUIFigure);
            app.ParametersTab = uitab(app.TabGroup2);
            app.ParametersTab.Title = 'Parameters';
            app.RsEditFieldLabel = uilabel(app.ParametersTab);
            app.RsEditFieldLabel.Text = 'Rs';
            app.RsEditField = uieditfield(app.ParametersTab, 'numeric');
            app.RsEditField.Value = 2;
            app.RrEditFieldLabel = uilabel(app.ParametersTab);
            app.RrEditFieldLabel.Text = 'R''r';
            app.RrEditField = uieditfield(app.ParametersTab, 'numeric');
            app.RrEditField.Value = 1.7;
            app.LlsEditFieldLabel = uilabel(app.ParametersTab);
            app.LlsEditFieldLabel.Text = 'Lls';
            app.LlsEditField = uieditfield(app.ParametersTab, 'numeric');
            app.LlsEditField.Value = 0.008;
            app.LmEditFieldLabel = uilabel(app.ParametersTab);
            app.LmEditFieldLabel.Text = 'Lm';
            app.LmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.LmEditField.Value = 0.46;
            app.SetParametersButton = uibutton(app.ParametersTab, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.LlrEditFieldLabel = uilabel(app.ParametersTab);
            app.LlrEditFieldLabel.Text = 'L''lr';
            app.LlrEditField = uieditfield(app.ParametersTab, 'numeric');
            app.LlrEditField.Value = 0.012;
            app.JEditFieldLabel = uilabel(app.ParametersTab);
            app.JEditFieldLabel.Text = 'J';
            app.JEditField = uieditfield(app.ParametersTab, 'numeric');
            app.JEditField.Value = 0.03;
            app.PEditFieldLabel = uilabel(app.ParametersTab);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.ParametersTab, 'numeric');
            app.PEditField.Value = 2;
            app.BmEditFieldLabel = uilabel(app.ParametersTab);
            app.BmEditFieldLabel.Text = 'Bm';
            app.BmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.BmEditField.Value = 0.02;
            app.MotorPickerButtonGroup = uibuttongroup(app.ParametersTab);
            app.MotorPickerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MotorPickerButtonGroupSelectionChanged, true);
            app.MotorPickerButtonGroup.Title = 'Motor Picker';
            app.Motor1Button = uiradiobutton(app.MotorPickerButtonGroup);
            app.Motor1Button.Text = 'Motor 1';
            app.Motor1Button.Value = true;
            app.Motor2Button = uiradiobutton(app.MotorPickerButtonGroup);
            app.Motor2Button.Text = 'Motor 2';
            app.Motor3Button = uiradiobutton(app.MotorPickerButtonGroup);
            app.Motor3Button.Text = 'Motor 3';
            app.Motor4Button = uiradiobutton(app.MotorPickerButtonGroup);
            app.Motor4Button.Text = 'Motor 4';
            app.Motor5Button = uiradiobutton(app.MotorPickerButtonGroup);
            app.Motor5Button.Text = 'Custom';
            app.Image = uiimage(app.ParametersTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @MotorPickerButtonGroupSelectionChanged, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img_motor1.jpg');
            app.SimulateTab = uitab(app.TabGroup2);
            app.SimulateTab.Title = 'Simulate';
            app.SimulateButton = uibutton(app.SimulateTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.FrequencySlider = uislider(app.SimulateTab);
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.FrequencySlider.Value = 50;
            app.FrequencySliderLabel = uilabel(app.SimulateTab);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.AmplitudeSlider = uislider(app.SimulateTab);
            app.AmplitudeSlider.Limits = [0 500];
            app.AmplitudeSlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSliderValueChanging, true);
            app.AmplitudeSlider.Value = 325;
            app.AmplitudeSliderLabel = uilabel(app.SimulateTab);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.FrequencyEditFieldLabel = uilabel(app.SimulateTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SimulateTab, 'numeric');
            app.FrequencyEditField.Limits = [0 100];
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 50;
            app.AmplitudeEditFieldLabel = uilabel(app.SimulateTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.SimulateTab, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.AmplitudeEditField.Value = 325;
            app.SimStopTimeEditFieldLabel = uilabel(app.SimulateTab);
            app.SimStopTimeEditFieldLabel.Text = 'Sim Stop Time';
            app.SimStopTimeEditField = uieditfield(app.SimulateTab, 'numeric');
            app.SimStopTimeEditField.Value = 2;
            app.SimStepSizeEditFieldLabel = uilabel(app.SimulateTab);
            app.SimStepSizeEditFieldLabel.Text = 'Sim Step Size';
            app.SimStepSizeEditField = uieditfield(app.SimulateTab, 'numeric');
            app.SimStepSizeEditField.Value = 0.0001;
            app.Switch = uiswitch(app.SimulateTab, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.VirtualRealityLabel = uilabel(app.SimulateTab);
            app.VirtualRealityLabel.Text = 'Virtual Reality';
            app.HTML = uihtml(app.SimulateTab);
            app.HTML.HTMLSource = 'myHtml.html';
            % Show the figure after all components are created
            app.AsynchronousMotorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = my_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AsynchronousMotorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AsynchronousMotorUIFigure)
        end
    end
end